classdef tpAppStroke < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        VerificarPrecisoButton         matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        TreinarRedeNeuronalPanel       matlab.ui.container.Panel
        testEditField                  matlab.ui.control.EditField
        testEditFieldLabel             matlab.ui.control.Label
        valEditField                   matlab.ui.control.EditField
        valEditFieldLabel              matlab.ui.control.Label
        trainEditField                 matlab.ui.control.EditField
        trainEditFieldLabel            matlab.ui.control.Label
        FunodeAtivao2DropDown          matlab.ui.control.DropDown
        FunodeAtivao2Label             matlab.ui.control.Label
        FunodeAtivao1DropDown          matlab.ui.control.DropDown
        FunodeAtivao1DropDownLabel     matlab.ui.control.Label
        pocasdeTreinamentoEditField    matlab.ui.control.EditField
        pocasdeTreinamentoEditFieldLabel  matlab.ui.control.Label
        FunodeDivisoDropDown           matlab.ui.control.DropDown
        FunodeDivisoDropDownLabel      matlab.ui.control.Label
        FunodeTreinoDropDown           matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel      matlab.ui.control.Label
        NmerodeNeurniosEditField       matlab.ui.control.EditField
        NmerodeNeurniosEditFieldLabel  matlab.ui.control.Label
        TreinarRedeNeuronalButton      matlab.ui.control.Button
        CreditsTab                     matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        RicardoAlmeidadeAguiarTavares2021144652Label  matlab.ui.control.Label
        JooMotaChoupina2020Label       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        CreditsLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ToggleToolClicked(app, event)
            
        end

        % Button pushed function: TreinarRedeNeuronalButton
        function TreinarRedeNeuronalButtonPushed(app, event)
            % Simulação de chamada da função feedforwardTRAIN com valores fictícios
            layerNeurons = str2num(app.NmerodeNeurniosEditField.Value); % Número de neurônios na camada oculta
            if (isempty(app.NmerodeNeurniosEditField.Value))
                errordlg('O campo "Números de neurônios" não pode estar vazio!','ERRO');
                return;
            end
            epochs = str2num(app.pocasdeTreinamentoEditField.Value); % Número de épocas de treinamento
            if (isempty(app.pocasdeTreinamentoEditField.Value))
                errordlg('O campo "Números de épocas de treinamento" não pode estar vazio!','ERRO');
                return;
            end
            trainFunc = app.FunodeTreinoDropDown.Value; % Função de treinamento da rede neural
            divFunc = app.FunodeDivisoDropDown.Value; % Função de divisão dos dados
            evalFunc = {app.FunodeAtivao1DropDown.Value, app.FunodeAtivao2DropDown.Value}; % Funções de ativação das camadas oculta e de saída
            trainRatio = str2double(app.trainEditField.Value); % Proporção dos dados de treinamento
            if (isempty(app.trainEditField.Value))
                errordlg('O campo "train" não pode estar vazio!','ERRO');
                return;
            end
            valRatio = str2double(app.valEditField.Value); % Proporção dos dados de validação
            if (isempty(app.valEditField.Value))
                errordlg('O campo "val" não pode estar vazio!','ERRO');
                return;
            end
            testRatio = str2double(app.testEditField.Value); % Proporção dos dados de teste
            if (isempty(app.testEditField.Value))
                errordlg('O campo "test" não pode estar vazio!','ERRO');
                return;
            end
                
            % Chamada simulada da função feedforwardTRAIN com os parâmetros fornecidos
            outputBuffer = evalc('[totalAccuracy, testAccuracy, timeElapsed] = feedforwardTRAIN(layerNeurons, epochs, trainFunc, divFunc, evalFunc, trainRatio, valRatio, testRatio);');
            
            % Exibindo o resultado na TextArea
            app.TextArea.Value = '';
            app.TextArea.Value = outputBuffer;

        end

        % Button pushed function: VerificarPrecisoButton
        function VerificarPrecisoButtonPushed(app, event)
            outputBuffer = evalc('feedforwardTEST();');
            
            % Exibindo o resultado na TextArea
            app.TextArea.Value = '';
            app.TextArea.Value = outputBuffer;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.TreinarRedeNeuronalPanel = uipanel(app.HomeTab);
            app.TreinarRedeNeuronalPanel.Title = 'Treinar Rede Neuronal';
            app.TreinarRedeNeuronalButton = uibutton(app.TreinarRedeNeuronalPanel, 'push');
            app.TreinarRedeNeuronalButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeNeuronalButtonPushed, true);
            app.TreinarRedeNeuronalButton.Text = 'Treinar Rede Neuronal';
            app.NmerodeNeurniosEditFieldLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.NmerodeNeurniosEditFieldLabel.Text = 'Número de Neurónios';
            app.NmerodeNeurniosEditField = uieditfield(app.TreinarRedeNeuronalPanel, 'text');
            app.NmerodeNeurniosEditField.Value = '[10]';
            app.FunodeTreinoDropDownLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.FunodeTreinoDropDown = uidropdown(app.TreinarRedeNeuronalPanel);
            app.FunodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', 'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};
            app.FunodeTreinoDropDown.Value = 'trainlm';
            app.FunodeDivisoDropDownLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.FunodeDivisoDropDownLabel.Text = 'Função de Divisão';
            app.FunodeDivisoDropDown = uidropdown(app.TreinarRedeNeuronalPanel);
            app.FunodeDivisoDropDown.Items = {'dividerand'};
            app.FunodeDivisoDropDown.Value = 'dividerand';
            app.pocasdeTreinamentoEditFieldLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.pocasdeTreinamentoEditFieldLabel.Text = 'Épocas de Treinamento';
            app.pocasdeTreinamentoEditField = uieditfield(app.TreinarRedeNeuronalPanel, 'text');
            app.pocasdeTreinamentoEditField.Value = '1000';
            app.FunodeAtivao1DropDownLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.FunodeAtivao1DropDownLabel.Text = 'Função de Ativação 1';
            app.FunodeAtivao1DropDown = uidropdown(app.TreinarRedeNeuronalPanel);
            app.FunodeAtivao1DropDown.Items = {'tansig', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tribas', 'compet'};
            app.FunodeAtivao1DropDown.Value = 'tansig';
            app.FunodeAtivao2Label = uilabel(app.TreinarRedeNeuronalPanel);
            app.FunodeAtivao2Label.Text = 'Função de Ativação 2';
            app.FunodeAtivao2DropDown = uidropdown(app.TreinarRedeNeuronalPanel);
            app.FunodeAtivao2DropDown.Items = {'purelin', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas', 'compet'};
            app.FunodeAtivao2DropDown.Value = 'purelin';
            app.trainEditFieldLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.trainEditFieldLabel.Text = 'train';
            app.trainEditField = uieditfield(app.TreinarRedeNeuronalPanel, 'text');
            app.trainEditField.Value = '0.7';
            app.valEditFieldLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.valEditFieldLabel.Text = 'val';
            app.valEditField = uieditfield(app.TreinarRedeNeuronalPanel, 'text');
            app.valEditField.Value = '0.15';
            app.testEditFieldLabel = uilabel(app.TreinarRedeNeuronalPanel);
            app.testEditFieldLabel.Text = 'test';
            app.testEditField = uieditfield(app.TreinarRedeNeuronalPanel, 'text');
            app.testEditField.Value = '0.15';
            app.TextArea = uitextarea(app.HomeTab);
            app.VerificarPrecisoButton = uibutton(app.HomeTab, 'push');
            app.VerificarPrecisoButton.ButtonPushedFcn = createCallbackFcn(app, @VerificarPrecisoButtonPushed, true);
            app.VerificarPrecisoButton.Text = 'Verificar Precisão';
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.CreditsLabel = uilabel(app.CreditsTab);
            app.CreditsLabel.Text = 'Credits';
            app.Label = uilabel(app.CreditsTab);
            app.Label.Text = '08 / 05 - 2024';
            app.JooMotaChoupina2020Label = uilabel(app.CreditsTab);
            app.JooMotaChoupina2020Label.Text = 'João Mota Choupina - 2020';
            app.RicardoAlmeidadeAguiarTavares2021144652Label = uilabel(app.CreditsTab);
            app.RicardoAlmeidadeAguiarTavares2021144652Label.Text = 'Ricardo Almeida de Aguiar Tavares - 2021144652';
            app.Image = uiimage(app.CreditsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logoIsec.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tpAppStroke

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end